classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        right_panel_container          matlab.ui.container.Panel
        main_panel                     matlab.ui.container.Panel
        EncadrerparPrIELMOURABITLabel  matlab.ui.control.Label
        Hyperlink                      matlab.ui.control.Hyperlink
        Image2                         matlab.ui.control.Image
        RaliserparSAFIOussamaLabel     matlab.ui.control.Label
        ModuleTraitementdesSignauxMultimdiaetrseauxdecapteursLabel  matlab.ui.control.Label
        ApplicationdetraitementdimageLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        MSTIngnieriedesSystmesEmbarqusRseauxetTlcommunicationsLabel  matlab.ui.control.Label
        volet1_panel                   matlab.ui.container.Panel
        SauvgarderlimageButton_3       matlab.ui.control.Button
        ImageaprsletraitementLabel     matlab.ui.control.Label
        ImageOriginalLabel             matlab.ui.control.Label
        error_label                    matlab.ui.control.Label
        NouvellerepresentationEditField  matlab.ui.control.NumericEditField
        NouvellerepresentationLabel    matlab.ui.control.Label
        RequantificationButton         matlab.ui.control.Button
        PassageenniveauxdegrisButton   matlab.ui.control.Button
        Taille                         matlab.ui.control.Label
        TailleLabel                    matlab.ui.control.Label
        AjouterButton                  matlab.ui.control.Button
        Pross_img_display_volet1       matlab.ui.control.UIAxes
        Org_img_display_volet1         matlab.ui.control.UIAxes
        volet3_panel                   matlab.ui.container.Panel
        SauvgarderlimageButton_2       matlab.ui.control.Button
        Taille_EditField               matlab.ui.control.NumericEditField
        VariancedubruitEditFieldLabel_3  matlab.ui.control.Label
        TypedufiltreButtonGroup_2      matlab.ui.container.ButtonGroup
        RobertsButton                  matlab.ui.control.RadioButton
        SobelButton                    matlab.ui.control.RadioButton
        prewittButton                  matlab.ui.control.RadioButton
        Ordre_EditField                matlab.ui.control.NumericEditField
        VariancedubruitEditFieldLabel_2  matlab.ui.control.Label
        TypedufiltreButtonGroup        matlab.ui.container.ButtonGroup
        MoyonneurButton                matlab.ui.control.RadioButton
        DordreButton                   matlab.ui.control.RadioButton
        MedianeButton                  matlab.ui.control.RadioButton
        Filtrer_Button                 matlab.ui.control.Button
        VariancedubruitEditField       matlab.ui.control.NumericEditField
        VariancedubruitEditFieldLabel  matlab.ui.control.Label
        TypedubruitButtonGroup         matlab.ui.container.ButtonGroup
        PoissonButton                  matlab.ui.control.RadioButton
        SpeckleButton                  matlab.ui.control.RadioButton
        GaussianButton                 matlab.ui.control.RadioButton
        SaltPepperButton               matlab.ui.control.RadioButton
        COntour_Button                 matlab.ui.control.Button
        AjouterButton_volet3_2         matlab.ui.control.Button
        ImageaprsletraitementLabel_3   matlab.ui.control.Label
        ImageOriginalLabel_3           matlab.ui.control.Label
        error_label_3                  matlab.ui.control.Label
        AjouterButton_volet3           matlab.ui.control.Button
        Pross_img_display_volet3       matlab.ui.control.UIAxes
        Org_img_display_volet3         matlab.ui.control.UIAxes
        volet2_panel                   matlab.ui.container.Panel
        SauvgarderlimageButton         matlab.ui.control.Button
        ImageaprsegalisationdhistogrammeButton  matlab.ui.control.Button
        error_label_2                  matlab.ui.control.Label
        AjouterButton_2                matlab.ui.control.Button
        BrillanceKnob                  matlab.ui.control.Knob
        BrillanceKnobLabel             matlab.ui.control.Label
        EgalisationdhistogrammeButton  matlab.ui.control.Button
        HistogrammeButton              matlab.ui.control.Button
        CorrectiongammaButton          matlab.ui.control.Button
        GammaEditField                 matlab.ui.control.NumericEditField
        GammaEditFieldLabel            matlab.ui.control.Label
        ImageaprsletraitementLabel_2   matlab.ui.control.Label
        ImageOriginalLabel_2           matlab.ui.control.Label
        Pross_data_display_volet2      matlab.ui.control.UIAxes
        Org_img_display_volet2         matlab.ui.control.UIAxes
        left_panel                     matlab.ui.container.Panel
        QuitterButton                  matlab.ui.control.Button
        meVoletButton                  matlab.ui.control.Button
        meVoletButton_2                matlab.ui.control.Button
        erVoletButton                  matlab.ui.control.Button
        AccueilButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Image_Volet1;
        Image_Volet2;
        Image_Volet3;% Description
        pross_image_v1;
        pross_image_v2;
        pross_image_v3;
        brillance;
        empty = zeros(256);
        empty_img;
        requantification_value;
        noise_radio;
        variance;
        ordre_filtre;
        taille_filtre;
        pathToMLAPP = fileparts(mfilename('fullpath'));
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.main_panel.Visible = "on";
            app.volet1_panel.Visible= "off";
            app.volet2_panel.Visible= "off";
            app.volet3_panel.Visible= "off";


        end

        % Button pushed function: AccueilButton
        function AccueilButtonPushed(app, event)
            app.main_panel.Visible = "on";
            app.volet1_panel.Visible= "off";
            app.volet2_panel.Visible= "off";
            app.volet3_panel.Visible= "off";

            app.AccueilButton.Icon = fullfile(app.pathToMLAPP, 'accueil.png');
            app.erVoletButton.Icon = fullfile(app.pathToMLAPP, 'volet_gris.png');
            app.meVoletButton.Icon = fullfile(app.pathToMLAPP, 'volet_gris.png');
            app.meVoletButton_2.Icon = fullfile(app.pathToMLAPP, 'volet_gris.png');
        end

        % Button pushed function: erVoletButton
        function erVoletButtonPushed(app, event)
            app.main_panel.Visible = "off";
            app.volet1_panel.Visible= "on";
            app.volet2_panel.Visible= "off";
            app.volet3_panel.Visible= "off";

            app.AccueilButton.Icon = fullfile(app.pathToMLAPP, 'accueil_black.png');
            app.erVoletButton.Icon = fullfile(app.pathToMLAPP, 'vlet_couleur.png');
            app.meVoletButton.Icon = fullfile(app.pathToMLAPP, 'volet_gris.png');
            app.meVoletButton_2.Icon = fullfile(app.pathToMLAPP, 'volet_gris.png');
            
        end

        % Button pushed function: meVoletButton_2
        function meVoletButton_2Pushed(app, event)
            app.main_panel.Visible = "off";
            app.volet1_panel.Visible= "off";
            app.volet2_panel.Visible= "on";
            app.volet3_panel.Visible= "off";

             app.AccueilButton.Icon = fullfile(app.pathToMLAPP, 'accueil_black.png');
            app.erVoletButton.Icon = fullfile(app.pathToMLAPP, 'volet_gris.png');
            app.meVoletButton.Icon = fullfile(app.pathToMLAPP, 'volet_gris.png');
            app.meVoletButton_2.Icon = fullfile(app.pathToMLAPP, 'vlet_couleur.png');
            
            
        end

        % Button pushed function: meVoletButton
        function meVoletButtonPushed(app, event)
            app.main_panel.Visible = "off";
            app.volet1_panel.Visible= "off";
            app.volet2_panel.Visible= "off";
            app.volet3_panel.Visible= "on";
            app.AccueilButton.Icon = fullfile(app.pathToMLAPP, 'accueil_black.png');
            app.erVoletButton.Icon = fullfile(app.pathToMLAPP, 'volet_gris.png');
            app.meVoletButton.Icon = fullfile(app.pathToMLAPP, 'vlet_couleur.png');
            app.meVoletButton_2.Icon = fullfile(app.pathToMLAPP, 'volet_gris.png');
            
        end

        % Button pushed function: QuitterButton
        function QuitterButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: AjouterButton
        function AjouterButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.gif'}, 'Select an image file');
        if isequal(fileName, 0)
            % User canceled file selection
            return;
        end
        % Construct the full file path
        selectedFile = fullfile(pathName, fileName);
        
        % Read the selected image
        app.Image_Volet1 = imread(selectedFile);
        [M,N,Z] = size(app.Image_Volet1);
        imshow(app.Image_Volet1, 'Parent', app.Org_img_display_volet1);
        
        app.Taille.Text = M+"x"+N+"x"+Z;
        app.error_label.Text = "";
       
        end

        % Button pushed function: PassageenniveauxdegrisButton
        function PassageenniveauxdegrisButtonPushed(app, event)
            [a,b,z] = size(app.Image_Volet1);
            if a ~= 0 && b ~= 0 && z == 3
                app.pross_image_v1 = rgb_to_gray(app.Image_Volet1,a,b);
                imshow(app.pross_image_v1, 'Parent', app.Pross_img_display_volet1);
            elseif z ~= 3  && a ~= 0 && b ~= 0
                app.error_label.Text = "L'image importer est déja en niveau de gris";
            elseif a == 0 && b == 0
                app.error_label.Text = "Veullez importer d'abord une image";
            end
        end

        % Button pushed function: RequantificationButton
        function RequantificationButtonPushed(app, event)
            app.requantification_value = app.NouvellerepresentationEditField.Value;
            [a,b,z] = size(app.Image_Volet1);
            if a ~= 0 && b ~= 0 
                if app.requantification_value <= 0
                    app.error_label.Text = "La nouvelle representation doit etre strictement supérieur à 0";
                elseif app.requantification_value > 8 
                    app.error_label.Text = "La nouvelle representation doit etre inférieur ou égale à 8";
                else
                    if z == 3
                        app.pross_image_v1 = requantification_RGB(app.Image_Volet1, app.requantification_value);
                        imshow(app.pross_image_v1, 'Parent', app.Pross_img_display_volet1);
                        app.error_label.Text = "";
                    elseif z==1
                        app.pross_image_v1 = requantification_gray(app.Image_Volet1, app.requantification_value);
                        imshow(app.pross_image_v1, 'Parent', app.Pross_img_display_volet1);
                        app.error_label.Text = "";
                    end
                end
            else
                app.error_label.Text = "Veullez importer d'abord une image";
            end
            
        end

        % Button pushed function: HistogrammeButton
        function HistogrammeButtonPushed(app, event)
            [M,N,z]  = size(app.Image_Volet2);
            if z == 3
                a = rgb_to_gray(app.Image_Volet2,M,N);
            else
                a = app.Image_Volet2;
            end
            if M~=0 && N~=0
                app.pross_image_v2 = histogram__image(a);
                m = max(app.pross_image_v2);
                
                C1= 1:1:256;
                reset(app.Pross_data_display_volet2);
                
                 app.Pross_data_display_volet2.XLim=[1,256];
                app.Pross_data_display_volet2.YLim=[1,m+1];
                plot(C1,app.pross_image_v2, 'Parent', app.Pross_data_display_volet2);
                app.error_label_2.Text = "";
            else
                app.error_label_2.Text = "Veullez importer d'abord une image";
            end
        end

        % Button pushed function: AjouterButton_2
        function AjouterButton_2Pushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.gif';'*.tif'}, 'Select an image file');
        if isequal(fileName, 0)
            % User canceled file selection
            return;
        end
        % Construct the full file path
        selectedFile = fullfile(pathName, fileName);
        
        % Read the selected image
        app.Image_Volet2 = imread(selectedFile);
        
        imshow(app.Image_Volet2, 'Parent', app.Org_img_display_volet2);
        app.error_label_2.Text = "";
        end

        % Value changing function: BrillanceKnob
        function BrillanceKnobValueChanging(app, event)
            changingValue = event.Value;
            [M,N,~]  = size(app.Image_Volet2);
            C1= 1:1:256;
            if M~=0 && N~=0

                
                app.pross_image_v2 = (changingValue * app.Image_Volet2);
                
                reset(app.Pross_data_display_volet2);
                
                imshow(app.pross_image_v2, 'Parent', app.Pross_data_display_volet2);
                app.error_label_2.Text = "";
            else
                app.error_label_2.Text = "Veullez importer d'abord une image";
            end
        end

        % Button pushed function: EgalisationdhistogrammeButton
        function EgalisationdhistogrammeButtonPushed(app, event)
            [M,N,~]  = size(app.Image_Volet2);
            if M~=0 && N~=0
                [app.pross_image_v2,~] = Egalisation_histo_func(app.Image_Volet2);
                m = max(app.pross_image_v2);
                

                reset(app.Pross_data_display_volet2);
                app.Pross_data_display_volet2.XLim=[1,256];
                app.Pross_data_display_volet2.YLim=[1,m+1];
                plot(app.pross_image_v2, 'Parent', app.Pross_data_display_volet2);
                app.error_label_2.Text = "";
            
            else
                app.error_label_2.Text = "Veullez importer d'abord une image";
            end
        end

        % Button pushed function: ImageaprsegalisationdhistogrammeButton
        function ImageaprsegalisationdhistogrammeButtonPushed(app, event)
            [M,N,Z]  = size(app.Image_Volet2);
            if M~=0 && N~=0 && Z == 3
                app.pross_image_v2 = Egalisation_RGB_RGB(app.Image_Volet2);
               
                reset(app.Pross_data_display_volet2);
                imshow(app.pross_image_v2, 'Parent', app.Pross_data_display_volet2);
                
                app.error_label_2.Text = "";
            elseif M~=0 && N~=0 && Z == 1
                [~,app.pross_image_v2] = Egalisation_histo_func(app.Image_Volet2);
               
                reset(app.Pross_data_display_volet2);
                
                imshow(app.pross_image_v2, 'Parent', app.Pross_data_display_volet2);
                
                app.error_label_2.Text = "";
            else
                app.error_label_2.Text = "Veullez importer d'abord une image";
            end
        end

        % Button pushed function: CorrectiongammaButton
        function CorrectiongammaButtonPushed(app, event)
            gamma = app.GammaEditField.Value;
            [M,N,z]  = size(app.Image_Volet2);
            C1= 1:1:256;
            if M~=0 && N~=0
                if gamma > 0 

                    if z==3
                        app.pross_image_v2 = Correction_gamma_RGB(app.Image_Volet2,gamma);
                        reset(app.Pross_data_display_volet2);
                        imshow(app.pross_image_v2, 'Parent', app.Pross_data_display_volet2);
                        app.error_label_2.Text = "";
                    elseif z==1
                        app.pross_image_v2 = correction_gamma(app.Image_Volet2,gamma);
                        reset(app.Pross_data_display_volet2);
                        imshow(app.pross_image_v2, 'Parent', app.Pross_data_display_volet2);
                        app.error_label_2.Text = "";
                    end
                
                    
                else
                    app.error_label_2.Text = "Gamma doit etre supérieur à 0";
                end
            else
                app.error_label_2.Text = "Veullez importer d'abord une image";
            end
        end

        % Button pushed function: AjouterButton_volet3
        function AjouterButton_volet3Pushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.gif';'*.tif'}, 'Select an image file');
        if isequal(fileName, 0)
            % User canceled file selection
            return;
        end
        % Construct the full file path
        selectedFile = fullfile(pathName, fileName);
        
        % Read the selected image
        app.Image_Volet3 = imread(selectedFile);
        
        imshow(app.Image_Volet3, 'Parent', app.Org_img_display_volet3);
        app.error_label_3.Text = "";
        end

        % Button pushed function: AjouterButton_volet3_2
        function AjouterButton_volet3_2Pushed(app, event)
            [M,N,~] = size(app.Image_Volet3);
            app.variance = app.VariancedubruitEditField.Value;
            
            if M ~= 0 && N~= 0
                if app.variance >= 0 && app.variance <= 1
                    if  app.SpeckleButton.Value == true
                        app.pross_image_v3 = imnoise(app.Image_Volet3,'speckle',app.variance);
                        imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                        app.error_label_3.Text = "";
                    elseif app.SaltPepperButton.Value == true
                        app.pross_image_v3 = imnoise(app.Image_Volet3,'salt & pepper',app.variance);
                        imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                        app.error_label_3.Text = "";
                    elseif app.GaussianButton.Value == true
                        app.pross_image_v3 = imnoise(app.Image_Volet3,'gaussian',0,app.variance);
                        imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                        app.error_label_3.Text = "";
                    elseif app.PoissonButton.Value == true
                        app.pross_image_v3 = imnoise(app.Image_Volet3,'poisson');
                        imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                        app.error_label_3.Text = "";
                    end
                else
                    app.error_label_3.Text = "La valeur de la variance doit etre entre 0 et 1";
                end
            else
                app.error_label_3.Text = "Veullez importer d'abord une image";
            end
        end

        % Button pushed function: Filtrer_Button
        function Filtrer_ButtonPushed(app, event)
            [M,N,z] = size(app.Image_Volet3);
            app.ordre_filtre = app.Ordre_EditField.Value;
            app.taille_filtre = app.Taille_EditField.Value;
            
            if M ~= 0 && N~= 0
                
                if app.taille_filtre < M && app.taille_filtre < N
                    if mod(app.taille_filtre,2) == 1
                        if  app.MedianeButton.Value == true
                            if z==3
                                app.pross_image_v3 = filtre_mediane_RGB(app.Image_Volet3,app.taille_filtre);
                                imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                                app.error_label_3.Text = "";
                            elseif z==1
                                app.pross_image_v3 = filtre_mediane(app.Image_Volet3,app.taille_filtre);
                                imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                                app.error_label_3.Text = "";
                            end
                        elseif app.DordreButton.Value == true
                            if app.ordre_filtre > 0 && app.ordre_filtre <= app.taille_filtre
                                if z==3
                                    app.pross_image_v3 = filtre_d_ordre_RGB(app.Image_Volet3,app.taille_filtre,app.ordre_filtre);
                                    imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                                    app.error_label_3.Text = "";
                                elseif z==1
                                    app.pross_image_v3 = filtre_d_ordre(app.Image_Volet3,app.taille_filtre,app.ordre_filtre);
                                    imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                                    app.error_label_3.Text = "";
                                end
                            else
                                app.error_label_3.Text = "L'ordre du filtre doit etre supérieur à 0 et inférieur ou égale à la taille du filtre";
                            end
                        elseif app.MoyonneurButton.Value == true
                            if z==3
                                app.pross_image_v3 = filtre_moyonneur_RGB(app.Image_Volet3,app.taille_filtre);
                                imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                                app.error_label_3.Text = "";
                            elseif z==1
                                app.pross_image_v3 = filtre_moyonneur_RGB(app.Image_Volet3,app.taille_filtre);
                                imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                                app.error_label_3.Text = "";
                            end
                        end
                    else
                        app.error_label_3.Text = "La taille du filtre doit etre un nombre impaire";
                    end
                else
                    app.error_label_3.Text = "La taille du filtre doit etre inférieur a celle du l'image";
                end
            else
                app.error_label_3.Text = "Veullez importer d'abord une image";
            end
        end

        % Button pushed function: COntour_Button
        function COntour_ButtonPushed(app, event)
            [M,N,~] = size(app.Image_Volet3);
            app.variance = app.VariancedubruitEditField.Value;
            
            if M ~= 0 && N~= 0
                
                    if  app.RobertsButton.Value == true
                        app.pross_image_v3 = Contour(app.Image_Volet3,1);
                        imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                        app.error_label_3.Text = "";
                    elseif app.SobelButton.Value == true
                        app.pross_image_v3 = Contour(app.Image_Volet3,3);
                        imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                        app.error_label_3.Text = "";
                    elseif app.prewittButton.Value == true
                        app.pross_image_v3 = Contour(app.Image_Volet3,2);
                        imshow(app.pross_image_v3, 'Parent', app.Pross_img_display_volet3);
                        app.error_label_3.Text = "";
                    
                    end
                
            else
                app.error_label_3.Text = "Veullez importer d'abord une image";
            end
        end

        % Button pushed function: SauvgarderlimageButton
        function SauvgarderlimageButtonPushed(app, event)
            if ~isempty(app.pross_image_v2)
                    % Demander à l'utilisateur de choisir l'emplacement et le nom de fichier
                [file, path] = uiputfile({'*.png';'*.jpg';'*.bmp';'*.tif'}, 'Enregistrer l''image traitée');
                
                % Vérifier si l'utilisateur a appuyé sur Annuler
                if isequal(file, 0)
                    return;
                end
        
                % Créer le chemin complet du fichier
                fullFilePath = fullfile(path, file);
        
                % Sauvegarder l'image traitée
                imwrite(app.pross_image_v2, fullFilePath);
                app.error_label_2.Text = "";
            else
                app.error_label_2.Text = "Veuillez faire un traitement d'abord";
            end
        end

        % Button pushed function: SauvgarderlimageButton_2
        function SauvgarderlimageButton_2Pushed(app, event)
            if ~isempty(app.pross_image_v3)
                    % Demander à l'utilisateur de choisir l'emplacement et le nom de fichier
                [file, path] = uiputfile({'*.png';'*.jpg';'*.bmp';'*.tif'}, 'Enregistrer l''image traitée');
                
                % Vérifier si l'utilisateur a appuyé sur Annuler
                if isequal(file, 0)
                    return;
                end
        
                % Créer le chemin complet du fichier
                fullFilePath = fullfile(path, file);
        
                % Sauvegarder l'image traitée
                imwrite(app.pross_image_v3, fullFilePath);
                app.error_label_3.Text = "";
            else
                app.error_label_3.Text = "Veuillez faire un traitement d'abord";
            end
        end

        % Button pushed function: SauvgarderlimageButton_3
        function SauvgarderlimageButton_3Pushed(app, event)
            if ~isempty(app.pross_image_v1)
                    % Demander à l'utilisateur de choisir l'emplacement et le nom de fichier
                [file, path] = uiputfile({'*.png';'*.jpg';'*.bmp';'*.tif'}, 'Enregistrer l''image traitée');
                
                % Vérifier si l'utilisateur a appuyé sur Annuler
                if isequal(file, 0)
                    return;
                end
        
                % Créer le chemin complet du fichier
                fullFilePath = fullfile(path, file);
        
                % Sauvegarder l'image traitée
                imwrite(app.pross_image_v1, fullFilePath);
                 app.error_label.Text = "";
            else
                app.error_label.Text = "Veuillez faire un traitement d'abord";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.left_panel = uipanel(app.UIFigure);
            app.AccueilButton = uibutton(app.left_panel, 'push');
            app.AccueilButton.ButtonPushedFcn = createCallbackFcn(app, @AccueilButtonPushed, true);
            app.AccueilButton.Icon = fullfile(pathToMLAPP, 'accueil.png');
            app.AccueilButton.Text = 'Accueil';
            app.erVoletButton = uibutton(app.left_panel, 'push');
            app.erVoletButton.ButtonPushedFcn = createCallbackFcn(app, @erVoletButtonPushed, true);
            app.erVoletButton.Icon = fullfile(pathToMLAPP, 'volet_gris.png');
            app.erVoletButton.Text = '1er Volet';
            app.meVoletButton_2 = uibutton(app.left_panel, 'push');
            app.meVoletButton_2.ButtonPushedFcn = createCallbackFcn(app, @meVoletButton_2Pushed, true);
            app.meVoletButton_2.Icon = fullfile(pathToMLAPP, 'volet_gris.png');
            app.meVoletButton_2.Text = '2éme Volet';
            app.meVoletButton = uibutton(app.left_panel, 'push');
            app.meVoletButton.ButtonPushedFcn = createCallbackFcn(app, @meVoletButtonPushed, true);
            app.meVoletButton.Icon = fullfile(pathToMLAPP, 'volet_gris.png');
            app.meVoletButton.Text = '3éme Volet';
            app.QuitterButton = uibutton(app.left_panel, 'push');
            app.QuitterButton.ButtonPushedFcn = createCallbackFcn(app, @QuitterButtonPushed, true);
            app.QuitterButton.Icon = fullfile(pathToMLAPP, 'quitter.png');
            app.QuitterButton.Text = 'Quitter';
            app.right_panel_container = uipanel(app.UIFigure);
            app.volet2_panel = uipanel(app.right_panel_container);
            app.Org_img_display_volet2 = uiaxes(app.volet2_panel);
            app.Org_img_display_volet2.XTick = [];
            app.Org_img_display_volet2.YTick = [];
            app.Pross_data_display_volet2 = uiaxes(app.volet2_panel);
            app.Pross_data_display_volet2.ZTick = [];
            app.Pross_data_display_volet2.Visible = 'off';
            app.ImageOriginalLabel_2 = uilabel(app.volet2_panel);
            app.ImageOriginalLabel_2.Text = 'Image Original';
            app.ImageaprsletraitementLabel_2 = uilabel(app.volet2_panel);
            app.ImageaprsletraitementLabel_2.Text = 'Image aprés le traitement';
            app.GammaEditFieldLabel = uilabel(app.volet2_panel);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.volet2_panel, 'numeric');
            app.CorrectiongammaButton = uibutton(app.volet2_panel, 'push');
            app.CorrectiongammaButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectiongammaButtonPushed, true);
            app.CorrectiongammaButton.Icon = fullfile(pathToMLAPP, 'correction_gamma_icon.png');
            app.CorrectiongammaButton.Text = {'Correction '; 'gamma'};
            app.HistogrammeButton = uibutton(app.volet2_panel, 'push');
            app.HistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammeButtonPushed, true);
            app.HistogrammeButton.Icon = fullfile(pathToMLAPP, 'histogramme.png');
            app.HistogrammeButton.Text = 'Histogramme';
            app.EgalisationdhistogrammeButton = uibutton(app.volet2_panel, 'push');
            app.EgalisationdhistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @EgalisationdhistogrammeButtonPushed, true);
            app.EgalisationdhistogrammeButton.Icon = fullfile(pathToMLAPP, 'Egalisation_icon.png');
            app.EgalisationdhistogrammeButton.Text = {'Egalisation'; 'd''histogramme'};
            app.BrillanceKnobLabel = uilabel(app.volet2_panel);
            app.BrillanceKnobLabel.Text = 'Brillance';
            app.BrillanceKnob = uiknob(app.volet2_panel, 'continuous');
            app.BrillanceKnob.Limits = [0 1];
            app.BrillanceKnob.ValueChangingFcn = createCallbackFcn(app, @BrillanceKnobValueChanging, true);
            app.AjouterButton_2 = uibutton(app.volet2_panel, 'push');
            app.AjouterButton_2.ButtonPushedFcn = createCallbackFcn(app, @AjouterButton_2Pushed, true);
            app.AjouterButton_2.Icon = fullfile(pathToMLAPP, 'import_image.png');
            app.AjouterButton_2.Text = 'Ajouter';
            app.error_label_2 = uilabel(app.volet2_panel);
            app.error_label_2.Text = '';
            app.ImageaprsegalisationdhistogrammeButton = uibutton(app.volet2_panel, 'push');
            app.ImageaprsegalisationdhistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @ImageaprsegalisationdhistogrammeButtonPushed, true);
            app.ImageaprsegalisationdhistogrammeButton.Icon = fullfile(pathToMLAPP, 'image (2).png');
            app.ImageaprsegalisationdhistogrammeButton.Text = {'Image'; 'aprés egalisation'; 'd''histogramme'};
            app.SauvgarderlimageButton = uibutton(app.volet2_panel, 'push');
            app.SauvgarderlimageButton.ButtonPushedFcn = createCallbackFcn(app, @SauvgarderlimageButtonPushed, true);
            app.SauvgarderlimageButton.Icon = fullfile(pathToMLAPP, 'save_image_icon.png');
            app.SauvgarderlimageButton.Text = {'Sauvgarder'; 'l''image'};
            app.volet3_panel = uipanel(app.right_panel_container);
            app.Org_img_display_volet3 = uiaxes(app.volet3_panel);
            app.Org_img_display_volet3.XTick = [];
            app.Org_img_display_volet3.YTick = [];
            app.Pross_img_display_volet3 = uiaxes(app.volet3_panel);
            app.Pross_img_display_volet3.XTick = [];
            app.Pross_img_display_volet3.YTick = [];
            app.AjouterButton_volet3 = uibutton(app.volet3_panel, 'push');
            app.AjouterButton_volet3.ButtonPushedFcn = createCallbackFcn(app, @AjouterButton_volet3Pushed, true);
            app.AjouterButton_volet3.Icon = fullfile(pathToMLAPP, 'import_image.png');
            app.AjouterButton_volet3.Text = 'Ajouter';
            app.error_label_3 = uilabel(app.volet3_panel);
            app.error_label_3.Text = '';
            app.ImageOriginalLabel_3 = uilabel(app.volet3_panel);
            app.ImageOriginalLabel_3.Text = 'Image Original';
            app.ImageaprsletraitementLabel_3 = uilabel(app.volet3_panel);
            app.ImageaprsletraitementLabel_3.Text = 'Image aprés le traitement';
            app.AjouterButton_volet3_2 = uibutton(app.volet3_panel, 'push');
            app.AjouterButton_volet3_2.ButtonPushedFcn = createCallbackFcn(app, @AjouterButton_volet3_2Pushed, true);
            app.AjouterButton_volet3_2.Icon = fullfile(pathToMLAPP, 'noise_icon.png');
            app.AjouterButton_volet3_2.Text = {'Ajouter '; 'le bruit'};
            app.COntour_Button = uibutton(app.volet3_panel, 'push');
            app.COntour_Button.ButtonPushedFcn = createCallbackFcn(app, @COntour_ButtonPushed, true);
            app.COntour_Button.Icon = fullfile(pathToMLAPP, 'detection_icon.png');
            app.COntour_Button.Text = {'Detecter '; 'le contour'};
            app.TypedubruitButtonGroup = uibuttongroup(app.volet3_panel);
            app.TypedubruitButtonGroup.Title = 'Type du bruit';
            app.SaltPepperButton = uiradiobutton(app.TypedubruitButtonGroup);
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.SaltPepperButton.Value = true;
            app.GaussianButton = uiradiobutton(app.TypedubruitButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.SpeckleButton = uiradiobutton(app.TypedubruitButtonGroup);
            app.SpeckleButton.Text = 'Speckle';
            app.PoissonButton = uiradiobutton(app.TypedubruitButtonGroup);
            app.PoissonButton.Text = 'Poisson';
            app.VariancedubruitEditFieldLabel = uilabel(app.volet3_panel);
            app.VariancedubruitEditFieldLabel.Text = 'Variance du bruit';
            app.VariancedubruitEditField = uieditfield(app.volet3_panel, 'numeric');
            app.Filtrer_Button = uibutton(app.volet3_panel, 'push');
            app.Filtrer_Button.ButtonPushedFcn = createCallbackFcn(app, @Filtrer_ButtonPushed, true);
            app.Filtrer_Button.Icon = fullfile(pathToMLAPP, 'filter_icon.png');
            app.Filtrer_Button.Text = 'Filtrer';
            app.TypedufiltreButtonGroup = uibuttongroup(app.volet3_panel);
            app.TypedufiltreButtonGroup.Title = 'Type du filtre';
            app.MedianeButton = uiradiobutton(app.TypedufiltreButtonGroup);
            app.MedianeButton.Text = 'Mediane';
            app.MedianeButton.Value = true;
            app.DordreButton = uiradiobutton(app.TypedufiltreButtonGroup);
            app.DordreButton.Text = 'D''ordre';
            app.MoyonneurButton = uiradiobutton(app.TypedufiltreButtonGroup);
            app.MoyonneurButton.Text = 'Moyonneur';
            app.VariancedubruitEditFieldLabel_2 = uilabel(app.volet3_panel);
            app.VariancedubruitEditFieldLabel_2.Text = 'Ordre du filtre d''ordre';
            app.Ordre_EditField = uieditfield(app.volet3_panel, 'numeric');
            app.TypedufiltreButtonGroup_2 = uibuttongroup(app.volet3_panel);
            app.TypedufiltreButtonGroup_2.Title = 'Type du filtre';
            app.prewittButton = uiradiobutton(app.TypedufiltreButtonGroup_2);
            app.prewittButton.Text = 'prewitt';
            app.prewittButton.Value = true;
            app.SobelButton = uiradiobutton(app.TypedufiltreButtonGroup_2);
            app.SobelButton.Text = 'Sobel';
            app.RobertsButton = uiradiobutton(app.TypedufiltreButtonGroup_2);
            app.RobertsButton.Text = 'Roberts';
            app.VariancedubruitEditFieldLabel_3 = uilabel(app.volet3_panel);
            app.VariancedubruitEditFieldLabel_3.Text = 'Taille du filtre';
            app.Taille_EditField = uieditfield(app.volet3_panel, 'numeric');
            app.SauvgarderlimageButton_2 = uibutton(app.volet3_panel, 'push');
            app.SauvgarderlimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SauvgarderlimageButton_2Pushed, true);
            app.SauvgarderlimageButton_2.Icon = fullfile(pathToMLAPP, 'save_image_icon.png');
            app.SauvgarderlimageButton_2.Text = {'Sauvgarder'; 'l''image'};
            app.volet1_panel = uipanel(app.right_panel_container);
            app.Org_img_display_volet1 = uiaxes(app.volet1_panel);
            app.Org_img_display_volet1.XTick = [];
            app.Org_img_display_volet1.YTick = [];
            app.Pross_img_display_volet1 = uiaxes(app.volet1_panel);
            app.Pross_img_display_volet1.XTick = [];
            app.Pross_img_display_volet1.YTick = [];
            app.AjouterButton = uibutton(app.volet1_panel, 'push');
            app.AjouterButton.ButtonPushedFcn = createCallbackFcn(app, @AjouterButtonPushed, true);
            app.AjouterButton.Icon = fullfile(pathToMLAPP, 'import_image.png');
            app.AjouterButton.Text = 'Ajouter';
            app.TailleLabel = uilabel(app.volet1_panel);
            app.TailleLabel.Text = 'Taille : ';
            app.Taille = uilabel(app.volet1_panel);
            app.Taille.Text = '';
            app.PassageenniveauxdegrisButton = uibutton(app.volet1_panel, 'push');
            app.PassageenniveauxdegrisButton.ButtonPushedFcn = createCallbackFcn(app, @PassageenniveauxdegrisButtonPushed, true);
            app.PassageenniveauxdegrisButton.Icon = fullfile(pathToMLAPP, 'rgb2gray_icon.png');
            app.PassageenniveauxdegrisButton.Text = {'Passage '; 'en niveaux '; 'de gris'};
            app.RequantificationButton = uibutton(app.volet1_panel, 'push');
            app.RequantificationButton.ButtonPushedFcn = createCallbackFcn(app, @RequantificationButtonPushed, true);
            app.RequantificationButton.Icon = fullfile(pathToMLAPP, 'pixels (1).png');
            app.RequantificationButton.Text = 'Re-quantification';
            app.NouvellerepresentationLabel = uilabel(app.volet1_panel);
            app.NouvellerepresentationLabel.Text = {'Nouvelle '; 'representation'};
            app.NouvellerepresentationEditField = uieditfield(app.volet1_panel, 'numeric');
            app.error_label = uilabel(app.volet1_panel);
            app.error_label.Text = '';
            app.ImageOriginalLabel = uilabel(app.volet1_panel);
            app.ImageOriginalLabel.Text = 'Image Original';
            app.ImageaprsletraitementLabel = uilabel(app.volet1_panel);
            app.ImageaprsletraitementLabel.Text = 'Image aprés le traitement';
            app.SauvgarderlimageButton_3 = uibutton(app.volet1_panel, 'push');
            app.SauvgarderlimageButton_3.ButtonPushedFcn = createCallbackFcn(app, @SauvgarderlimageButton_3Pushed, true);
            app.SauvgarderlimageButton_3.Icon = fullfile(pathToMLAPP, 'save_image_icon.png');
            app.SauvgarderlimageButton_3.Text = {'Sauvgarder'; 'l''image'};
            app.main_panel = uipanel(app.right_panel_container);
            app.MSTIngnieriedesSystmesEmbarqusRseauxetTlcommunicationsLabel = uilabel(app.main_panel);
            app.MSTIngnieriedesSystmesEmbarqusRseauxetTlcommunicationsLabel.Text = 'MST:  Ingénierie des Systèmes Embarqués, Réseaux et Télécommunications';
            app.Image = uiimage(app.main_panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'fstm.png');
            app.ApplicationdetraitementdimageLabel = uilabel(app.main_panel);
            app.ApplicationdetraitementdimageLabel.Text = 'Application de traitement d''image';
            app.ModuleTraitementdesSignauxMultimdiaetrseauxdecapteursLabel = uilabel(app.main_panel);
            app.ModuleTraitementdesSignauxMultimdiaetrseauxdecapteursLabel.Text = {'Module:  Traitement des Signaux Multimédia et réseaux '; 'de capteurs'};
            app.RaliserparSAFIOussamaLabel = uilabel(app.main_panel);
            app.RaliserparSAFIOussamaLabel.Text = 'Réaliser par: SAFI Oussama';
            app.Image2 = uiimage(app.main_panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Github.png');
            app.Hyperlink = uihyperlink(app.main_panel);
            app.Hyperlink.URL = 'https://github.com/oussamaSafi/Image_processing_matlab_app';
            app.Hyperlink.Text = 'Source Code';
            app.EncadrerparPrIELMOURABITLabel = uilabel(app.main_panel);
            app.EncadrerparPrIELMOURABITLabel.Text = 'Encadrer par: Pr I. EL MOURABIT ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end